classdef FS_DIFFRACTION < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PARAMETERVALUESPanel       matlab.ui.container.Panel
        ScreenDistanceLabel        matlab.ui.control.Label
        EditField_3                matlab.ui.control.NumericEditField
        SlitWidthLabel_3           matlab.ui.control.Label
        EditField_2                matlab.ui.control.NumericEditField
        WavelengthLabel            matlab.ui.control.Label
        EditField                  matlab.ui.control.NumericEditField
        VISIBLELIGHTSPECTRUMPanel  matlab.ui.container.Panel
        SpectrumAxes               matlab.ui.control.UIAxes
        PARAMETERSETTINGSPanel     matlab.ui.container.Panel
        ScreenDistanceSlider       matlab.ui.control.Slider
        ScreenDistanceSliderLabel  matlab.ui.control.Label
        SlitWidthmSlider           matlab.ui.control.Slider
        SlitWidthLabel_2           matlab.ui.control.Label
        WavelengthnmSlider         matlab.ui.control.Slider
        WavelengthnmSliderLabel    matlab.ui.control.Label
        DIFFRACTIONPATTERNPanel    matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    methods (Access = private)

        function simulation(app)
       
            %  Declaring Variables
            d = app.SlitWidthmSlider.Value*10^-6; % Slit width
            Lambda = app.WavelengthnmSlider.Value*10^-9; % Wavelength
            a = 2; % Amplitude of the incident light
            D = app.ScreenDistanceSlider.Value; % Distance between the screen and the slit
            app.EditField.Value = Lambda ;
            app.EditField_2.Value = d  ;
            app.EditField_3.Value = D;

            % calculations for the plot
            P = linspace(-0.5,0.5,10000); % genarating points on the screen
            x = (D^2 + P.^2).^0.5; % distance form the center of the slit widthto the points on the screen

            theta = atan(P/D); % angle substended at each  point on the screen
            Beta = (pi*d*sin(theta))./ Lambda;    % Beta calculation
            %% Intenstity distribution considering the central maxima
            Ao = a*d./x;    % for spherical waves
            Intensity = ones(size(Beta));
            for i = 1: length (Beta)
                if Beta(i) == 0
                    Intensity(i) = Ao(i)^2;
                else
                    Intensity(i) = Ao(i)^2*(sin(Beta(i))/ Beta(i))^2 ;
                end
            end
            plot(app.UIAxes,Beta,Intensity)
            
        end         
                function showWelcomeMessage(app)
            % Display a welcome message dialog
            welcomeTitle = 'Fraunhofer Single-Slit Diffraction Simulator';
            welcomeMessage = sprintf(['Welcome to the Fraunhofer Single-Slit Diffraction Simulator!\n\n' ...
                'This application allows you to:\n' ...
                '• Visualize diffraction patterns for single-slit experiments\n' ...
                '• Adjust wavelength (380-750 nm) using the slider\n' ...
                '• Modify slit width (10-100 μm) for different patterns\n' ...
                '• Change screen distance (1-5 m) to see effects\n' ...
                '• View the visible light spectrum for reference\n\n' ...
                'Instructions:\n' ...
                '1. Use the parameter sliders to adjust values\n' ...
                '2. Watch the diffraction pattern update in real-time\n' ...
                '3. Parameter values are displayed in the right panel\n' ...
                '4. The spectrum shows the wavelength range\n\n' ...
                'Enjoy exploring the fascinating world of light diffraction!']);
            
            % Create and display the message dialog
            uialert(app.UIFigure, welcomeMessage, welcomeTitle, ...
                'Icon', 'info', 'Modal', true);
        end

       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
     % Show welcome message first
            showWelcomeMessage(app);
    % Create visible electromagnetic spectrum image
    % This function generates a spectrum from violet (380nm) to red (750nm)
    
    % Define wavelength range (in nanometers)
    wavelengths = linspace(380, 750, 1000); % 380-750 nm range
    height = 100; % Height of the spectrum bar
    
    % Create RGB matrix
    spectrum_rgb = zeros(height, length(wavelengths), 3);
    
    % Convert wavelengths to RGB colors
    for i = 1:length(wavelengths)
        lambda = wavelengths(i);
        rgb = wavelengthToRGB(app,lambda);
        
        % Fill the entire height with the same color
        spectrum_rgb(:, i, 1) = rgb(1); % Red channel
        spectrum_rgb(:, i, 2) = rgb(2); % Green channel
        spectrum_rgb(:, i, 3) = rgb(3); % Blue channel
    end
    
    % Display the spectrum in your app's axes (assumes you have an axes named 'SpectrumAxes')
    % Replace 'SpectrumAxes' with the actual name of your axes component
    imshow(spectrum_rgb, 'Parent', app.SpectrumAxes);
    
    % Set up the axes properties
    app.SpectrumAxes.XLim = [1, length(wavelengths)];
    app.SpectrumAxes.YLim = [1, height];
    
    % Add wavelength labels
    tick_positions = [1, 167, 333, 500, 667, 833, 1000]; % Approximate positions
    tick_labels = {'380', '440', '500', '560', '620', '680', '750'};
    
    app.SpectrumAxes.XTick = tick_positions;
    app.SpectrumAxes.XTickLabel = tick_labels;
    app.SpectrumAxes.YTick = [];
    
    % Add title and labels
    
    xlabel(app.SpectrumAxes, 'Wavelength (nm)');
    
    % Make sure the axes is visible
    app.SpectrumAxes.Visible = 'on';
end

function rgb = wavelengthToRGB(app,wavelength)
    % Convert wavelength (in nm) to RGB color
    % Based on Dan Bruton's algorithm
    
    if wavelength >= 380 && wavelength < 440
        red = -(wavelength - 440) / (440 - 380);
        green = 0.0;
        blue = 1.0;
    elseif wavelength >= 440 && wavelength < 490
        red = 0.0;
        green = (wavelength - 440) / (490 - 440);
        blue = 1.0;
    elseif wavelength >= 490 && wavelength < 510
        red = 0.0;
        green = 1.0;
        blue = -(wavelength - 510) / (510 - 490);
    elseif wavelength >= 510 && wavelength < 580
        red = (wavelength - 510) / (580 - 510);
        green = 1.0;
        blue = 0.0;
    elseif wavelength >= 580 && wavelength < 645
        red = 1.0;
        green = -(wavelength - 645) / (645 - 580);
        blue = 0.0;
    elseif wavelength >= 645 && wavelength <= 750
        red = 1.0;
        green = 0.0;
        blue = 0.0;
    else
        red = 0.0;
        green = 0.0;
        blue = 0.0;
    end
    
    % Intensity correction for wavelengths near the vision limits
    if wavelength >= 380 && wavelength < 420
        factor = 0.3 + 0.7 * (wavelength - 380) / (420 - 380);
    elseif wavelength >= 420 && wavelength <= 700
        factor = 1.0;
    elseif wavelength > 700 && wavelength <= 750
        factor = 0.3 + 0.7 * (750 - wavelength) / (750 - 700);
    else
        factor = 0.0;
    end
    
    % Apply intensity factor and gamma correction
    gamma = 0.80;
    rgb = [red * factor, green * factor, blue * factor].^gamma;
    
    % Ensure values are in valid range
    rgb = max(0, min(1, rgb));

        end

        % Value changed function: WavelengthnmSlider
        function WavelengthnmSliderValueChanged(app, event)
           %Wave = app.WavelengthnmSlider.Value;
           
           simulation(app)
          

        end

        % Value changed function: SlitWidthmSlider
        function SlitWidthmSliderValueChanged(app, event)
           % value = app.SlitWidthmSlider.Value;
            if app.WavelengthnmSlider.Value >380
                if app.ScreenDistanceSlider.Value > 0.1
                    simulation(app)
                end
            end
        end

        % Value changed function: ScreenDistanceSlider
        function ScreenDistanceSliderValueChanged(app, event)
           % value = app.ScreenDistanceSlider.Value;
           if app.WavelengthnmSlider.Value >380
                if app.SlitWidthmSlider.Value > 1
                    simulation(app);
                end
            end

       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DIFFRACTIONPATTERNPanel = uipanel(app.UIFigure);
            app.DIFFRACTIONPATTERNPanel.Title = ' DIFFRACTION PATTERN';
            app.UIAxes = uiaxes(app.DIFFRACTIONPATTERNPanel);
            title(app.UIAxes, 'Fraunhofer Single-Slit Diffraction Intensity Pattern')
            xlabel(app.UIAxes, 'Diffraction Parameter β')
            ylabel(app.UIAxes, 'Light Intensity (Normalized)')
            app.PARAMETERSETTINGSPanel = uipanel(app.UIFigure);
            app.PARAMETERSETTINGSPanel.Tooltip = {'SET UP THE INPUT PARAMETERS FOR THE WAVELENGTH, SLIT WIDTH, & SCREEN DISTANCE'};
            app.PARAMETERSETTINGSPanel.Title = 'PARAMETER SETTINGS';
            app.WavelengthnmSliderLabel = uilabel(app.PARAMETERSETTINGSPanel);
            app.WavelengthnmSliderLabel.Text = 'Wavelength/nm';
            app.WavelengthnmSlider = uislider(app.PARAMETERSETTINGSPanel);
            app.WavelengthnmSlider.Limits = [380 750];
            app.WavelengthnmSlider.ValueChangedFcn = createCallbackFcn(app, @WavelengthnmSliderValueChanged, true);
            app.WavelengthnmSlider.Value = 380;
            app.SlitWidthLabel_2 = uilabel(app.PARAMETERSETTINGSPanel);
            app.SlitWidthLabel_2.Text = 'Slit  Width/μm';
            app.SlitWidthmSlider = uislider(app.PARAMETERSETTINGSPanel);
            app.SlitWidthmSlider.Limits = [10 100];
            app.SlitWidthmSlider.ValueChangedFcn = createCallbackFcn(app, @SlitWidthmSliderValueChanged, true);
            app.SlitWidthmSlider.Tooltip = {'Width of the slit in micrometers'};
            app.SlitWidthmSlider.Value = 10;
            app.ScreenDistanceSliderLabel = uilabel(app.PARAMETERSETTINGSPanel);
            app.ScreenDistanceSliderLabel.Text = 'Screen Distance';
            app.ScreenDistanceSlider = uislider(app.PARAMETERSETTINGSPanel);
            app.ScreenDistanceSlider.Limits = [1 5];
            app.ScreenDistanceSlider.ValueChangedFcn = createCallbackFcn(app, @ScreenDistanceSliderValueChanged, true);
            app.ScreenDistanceSlider.Tooltip = {'Screen distance from the  slit in meters'};
            app.ScreenDistanceSlider.Value = 1;
            app.VISIBLELIGHTSPECTRUMPanel = uipanel(app.UIFigure);
            app.VISIBLELIGHTSPECTRUMPanel.Title = 'VISIBLE LIGHT SPECTRUM';
            app.SpectrumAxes = uiaxes(app.VISIBLELIGHTSPECTRUMPanel);
            app.PARAMETERVALUESPanel = uipanel(app.UIFigure);
            app.PARAMETERVALUESPanel.Title = 'PARAMETER VALUES';
            app.EditField = uieditfield(app.PARAMETERVALUESPanel, 'numeric');
            app.WavelengthLabel = uilabel(app.PARAMETERVALUESPanel);
            app.WavelengthLabel.Text = 'Wavelength';
            app.EditField_2 = uieditfield(app.PARAMETERVALUESPanel, 'numeric');
            app.SlitWidthLabel_3 = uilabel(app.PARAMETERVALUESPanel);
            app.SlitWidthLabel_3.Text = 'Slit Width';
            app.EditField_3 = uieditfield(app.PARAMETERVALUESPanel, 'numeric');
            app.ScreenDistanceLabel = uilabel(app.PARAMETERVALUESPanel);
            app.ScreenDistanceLabel.Text = 'Screen Distance';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FS_DIFFRACTION

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end